classdef Checklist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChecklistUIFigure            matlab.ui.Figure
        NopersonisintherobotcellandthelightcurtainisactivatedCheckBox  matlab.ui.control.CheckBox
        Lamp                         matlab.ui.control.Lamp
        RestockicingandchocolateblocksCheckBox  matlab.ui.control.CheckBox
        MakesurethatallemergencystopbuttonsareresetCheckBox  matlab.ui.control.CheckBox
        MakesurethatboththeRobotandconveyorarepoweredCheckBox  matlab.ui.control.CheckBox
        RestockicingandchocolateblocksCheckBox_4  matlab.ui.control.CheckBox
        Lamp_2                       matlab.ui.control.Lamp
        Lamp_3                       matlab.ui.control.Lamp
        Lamp_4                       matlab.ui.control.Lamp
        Lamp_5                       matlab.ui.control.Lamp
        SafetyStartupChecklistLabel  matlab.ui.control.Label
        Knob                         matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = private)
        Property % Description
        CheckList = 0; 
        
    end
    
    methods (Access = private)
        function  isDone(app)
            disp(app.CheckList);
            if app.CheckList == 31
                app.Knob.Visible = 'on';
            else
                app.Knob.Visible = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % NopersonisintherobotcellandthelightcurtainisactivatedCheckBox
        function NopersonisintherobotcellandthelightcurtainisactivatedCheckBoxValueChanged(app, event)
            if app.NopersonisintherobotcellandthelightcurtainisactivatedCheckBox.Value
%                 app.Lamp.Enable = 'on';
                app.Lamp.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 1, 'uint8');
            else
%                 app.Lamp.Enable = 'off';
                app.Lamp.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 1, 0);
            end
            
            isDone(app);
        end

        % Value changed function: 
        % RestockicingandchocolateblocksCheckBox
        function RestockicingandchocolateblocksCheckBoxValueChanged(app, event)
            if app.RestockicingandchocolateblocksCheckBox.Value
                app.Lamp_2.Color = [0, 1, 0];
%                 app.Lamp_2.Enable = 'on';
                app.CheckList = bitor(app.CheckList, 2, 'uint8');
            else
%                 app.Lamp_2.Enable = 'off';
                app.Lamp_2.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 2, 0);
            end
            
            isDone(app);
        end

        % Value changed function: 
        % MakesurethatallemergencystopbuttonsareresetCheckBox
        function MakesurethatallemergencystopbuttonsareresetCheckBoxValueChanged(app, event)
            if app.MakesurethatallemergencystopbuttonsareresetCheckBox.Value
%                 app.Lamp_3.Enable = 'on';
                app.Lamp_3.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 4, 'uint8');
            else
%                 app.Lamp_3.Enable = 'off';
                app.Lamp_3.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 3, 0);
            end    
            
            isDone(app);
        end

        % Value changed function: 
        % MakesurethatboththeRobotandconveyorarepoweredCheckBox
        function MakesurethatboththeRobotandconveyorarepoweredCheckBoxValueChanged(app, event)
            if app.MakesurethatboththeRobotandconveyorarepoweredCheckBox.Value
%                 app.Lamp_4.Enable = 'on';
                app.Lamp_4.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 8, 'uint8');
            else
%                 app.Lamp_4.Enable = 'off';
                app.Lamp_4.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 4, 0);
            end  
            
            isDone(app);
        end

        % Value changed function: 
        % RestockicingandchocolateblocksCheckBox_4
        function RestockicingandchocolateblocksCheckBox_4ValueChanged(app, event)
            if app.RestockicingandchocolateblocksCheckBox_4.Value
%                 app.Lamp_5.Enable = 'on';
                app.Lamp_5.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 16, 'uint8');
            else
%                 app.Lamp_5.Enable = 'off';
                app.Lamp_5.Color = [1, 0 , 0];
                app.CheckList = bitset(app.CheckList, 5, 0);
            end  
            
            isDone(app);
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            if app.Knob.Value == "On"
                Main2
                delete(app.ChecklistUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChecklistUIFigure = uifigure('Visible', 'off');
            app.ChecklistUIFigure.Name = 'Checklist';
            app.NopersonisintherobotcellandthelightcurtainisactivatedCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.NopersonisintherobotcellandthelightcurtainisactivatedCheckBox.ValueChangedFcn = createCallbackFcn(app, @NopersonisintherobotcellandthelightcurtainisactivatedCheckBoxValueChanged, true);
            app.NopersonisintherobotcellandthelightcurtainisactivatedCheckBox.Text = {'Check that guards for the robot and conveyor have been installed'; ' properly'};
            app.Lamp = uilamp(app.ChecklistUIFigure);
            app.RestockicingandchocolateblocksCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.RestockicingandchocolateblocksCheckBox.ValueChangedFcn = createCallbackFcn(app, @RestockicingandchocolateblocksCheckBoxValueChanged, true);
            app.RestockicingandchocolateblocksCheckBox.Text = 'Restock icing and chocolate blocks';
            app.MakesurethatallemergencystopbuttonsareresetCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.MakesurethatallemergencystopbuttonsareresetCheckBox.ValueChangedFcn = createCallbackFcn(app, @MakesurethatallemergencystopbuttonsareresetCheckBoxValueChanged, true);
            app.MakesurethatallemergencystopbuttonsareresetCheckBox.Text = 'Make sure that all emergency stop buttons are reset';
            app.MakesurethatboththeRobotandconveyorarepoweredCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.MakesurethatboththeRobotandconveyorarepoweredCheckBox.ValueChangedFcn = createCallbackFcn(app, @MakesurethatboththeRobotandconveyorarepoweredCheckBoxValueChanged, true);
            app.MakesurethatboththeRobotandconveyorarepoweredCheckBox.Text = 'Make sure that both the Robot and conveyor are powered';
            app.RestockicingandchocolateblocksCheckBox_4 = uicheckbox(app.ChecklistUIFigure);
            app.RestockicingandchocolateblocksCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @RestockicingandchocolateblocksCheckBox_4ValueChanged, true);
            app.RestockicingandchocolateblocksCheckBox_4.Text = {'Check to make sure that all operators and personal are standing outside '; 'and that the interlocked doors are closed properly'};
            app.Lamp_2 = uilamp(app.ChecklistUIFigure);
            app.Lamp_3 = uilamp(app.ChecklistUIFigure);
            app.Lamp_4 = uilamp(app.ChecklistUIFigure);
            app.Lamp_5 = uilamp(app.ChecklistUIFigure);
            app.SafetyStartupChecklistLabel = uilabel(app.ChecklistUIFigure);
            app.SafetyStartupChecklistLabel.Text = 'Safety Startup Checklist';
            app.Knob = uiknob(app.ChecklistUIFigure, 'discrete');
            app.Knob.Items = {'Off', 'On'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Visible = 'off';
            % Show the figure after all components are created
            app.ChecklistUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Checklist

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChecklistUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChecklistUIFigure)
        end
    end
end